classdef cannyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UploadButton       matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        SigmaSliderLabel   matlab.ui.control.Label
        SigmaSlider        matlab.ui.control.Slider
        ThresholdPanel_2   matlab.ui.container.Panel
        GridLayout_2       matlab.ui.container.GridLayout
        HighSlider_2Label  matlab.ui.control.Label
        HighSlider         matlab.ui.control.Slider
        LowSlider_2Label   matlab.ui.control.Label
        LowSlider          matlab.ui.control.Slider
        ImageButtonGroup   matlab.ui.container.ButtonGroup
        OriginalButton     matlab.ui.control.RadioButton
        CannyButton        matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        loadedImage;
        cannyImage;
        isImageBlank = true;
    end
    
    methods (Access = private)
        
        function loadImage(app)
            app.cannyImage = edge(rgb2gray(app.loadedImage), 'Canny', [app.LowSlider.Value * app.HighSlider.Value, app.HighSlider.Value], app.SigmaSlider.Value);
            if app.OriginalButton.Value == 1
                imshow(app.loadedImage, 'Parent', app.UIAxes);                
            else
                imshow(app.cannyImage, 'Parent', app.UIAxes);
            end          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;'}, 'Select File to Open');
            drawnow;
            figure(app.UIFigure);
            if filename == 0
                return;
            end
            fullname = string([filepath, filename]);
            app.loadedImage = imread(fullname);
            app.isImageBlank = false;
            loadImage(app);
        end

        % Value changed function: HighSlider, LowSlider, SigmaSlider
        function HighSliderValueChanged(app, event)
            if app.isImageBlank return; end %#ok<SEPEX> 
            if app.HighSlider.Value == 1 app.HighSlider.Value = 0.9999; end %#ok<SEPEX> 
            if app.HighSlider.Value == 0 app.HighSlider.Value = 0.0001; end %#ok<SEPEX> 
            if app.LowSlider.Value == 0 app.LowSlider.Value = 0.0001; end %#ok<SEPEX>
            if app.LowSlider.Value == 1 app.LowSlider.Value = 0.9999; end %#ok<SEPEX>   
            if app.SigmaSlider.Value == 0 app.SigmaSlider.Value = 0.0001; end %#ok<SEPEX>
            loadImage(app);
        end

        % Selection changed function: ImageButtonGroup
        function ImageButtonGroupSelectionChanged(app, event)
            if app.OriginalButton.Value == 1
                imshow(app.loadedImage, 'Parent', app.UIAxes);                
            else
                imshow(app.cannyImage, 'Parent', app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = {'Upload'; ''};
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.SigmaSliderLabel = uilabel(app.GridLayout);
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.GridLayout);
            app.SigmaSlider.Limits = [0 5];
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.SigmaSlider.Value = 2.5;
            app.ThresholdPanel_2 = uipanel(app.UIFigure);
            app.ThresholdPanel_2.Title = 'Threshold';
            app.GridLayout_2 = uigridlayout(app.ThresholdPanel_2);
            app.HighSlider_2Label = uilabel(app.GridLayout_2);
            app.HighSlider_2Label.Text = 'High';
            app.HighSlider = uislider(app.GridLayout_2);
            app.HighSlider.Limits = [0 0.05];
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.HighSlider.Value = 0.01;
            app.LowSlider_2Label = uilabel(app.GridLayout_2);
            app.LowSlider_2Label.Text = 'Low (%)';
            app.LowSlider = uislider(app.GridLayout_2);
            app.LowSlider.Limits = [0 1];
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.LowSlider.Value = 0.5;
            app.ImageButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageButtonGroupSelectionChanged, true);
            app.OriginalButton = uiradiobutton(app.ImageButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.CannyButton = uiradiobutton(app.ImageButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.CannyButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cannyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end